classdef MaREA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaREAUIFigure                   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DatasetTab                      matlab.ui.container.Tab
        importDataset                   matlab.ui.control.Button
        AdddatasetButton                matlab.ui.control.Button
        DatasetLabel                    matlab.ui.control.Label
        GeneIDscolDropDownLabel         matlab.ui.control.Label
        GeneIDscolDropDown              matlab.ui.control.DropDown
        FirstpatientcolDropDownLabel    matlab.ui.control.Label
        FirstpatientcolDropDown         matlab.ui.control.DropDown
        GeneIdentifierListBoxLabel      matlab.ui.control.Label
        GeneIdentifierListBox           matlab.ui.control.ListBox
        DatasetsTextAreaLabel           matlab.ui.control.Label
        DatasetsTextArea                matlab.ui.control.TextArea
        DatasetName                     matlab.ui.control.Label
        MetabolicModelTab               matlab.ui.container.Tab
        MetabolicmodelListBoxLabel      matlab.ui.control.Label
        MetabolicmodelListBox           matlab.ui.control.ListBox
        MetabolicmodelLabel             matlab.ui.control.Label
        ModelTextAreaLabel              matlab.ui.control.Label
        ModelTextArea                   matlab.ui.control.TextArea
        SelectModelButton               matlab.ui.control.Button
        ModelGeneIdentifierLabel        matlab.ui.control.Label
        ModelGeneIdentifierListBox      matlab.ui.control.ListBox
        PreparedataTab                  matlab.ui.container.Tab
        DatasetsListBoxLabel            matlab.ui.control.Label
        DatasetsListBox                 matlab.ui.control.ListBox
        ComputeRASButton                matlab.ui.control.Button
        AdvancedOptionsSwitchLabel      matlab.ui.control.Label
        AdvancedOptionsSwitch           matlab.ui.control.Switch
        ResolveAandnanrulesSwitchLabel  matlab.ui.control.Label
        ResolveAandnanrulesSwitch       matlab.ui.control.Switch
        ConvertlogscaledtodecimalvaluesSwitchLabel  matlab.ui.control.Label
        ConvertlogscaledtodecimalvaluesSwitch  matlab.ui.control.Switch
        Threshold0transcriptSpinnerLabel  matlab.ui.control.Label
        Threshold0transcriptSpinner     matlab.ui.control.Spinner
        ParallelemodeSwitchLabel        matlab.ui.control.Label
        ParallelemodeSwitch             matlab.ui.control.Switch
        AnalysisTab                     matlab.ui.container.Tab
        DatasetswithRASListBoxLabel     matlab.ui.control.Label
        DatasetswithRASListBox          matlab.ui.control.ListBox
        TabGroup2                       matlab.ui.container.TabGroup
        EnrichmentTab                   matlab.ui.container.Tab
        ComparedatasetButton            matlab.ui.control.Button
        ModeDropDownLabel               matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        HypotesistestDropDownLabel      matlab.ui.control.Label
        HypotesistestDropDown           matlab.ui.control.DropDown
        FoldChangeDropDownLabel         matlab.ui.control.Label
        FoldChangeDropDown              matlab.ui.control.DropDown
        ColormapButton                  matlab.ui.control.Button
        pVthrTextAreaLabel              matlab.ui.control.Label
        pVthrTextArea                   matlab.ui.control.TextArea
        FCthrTextAreaLabel              matlab.ui.control.Label
        FCthrTextArea                   matlab.ui.control.TextArea
        KmeanclusteringTab              matlab.ui.container.Tab
        FromKLabel                      matlab.ui.control.Label
        FromKSpinner                    matlab.ui.control.Spinner
        toKSpinnerLabel                 matlab.ui.control.Label
        toKSpinner                      matlab.ui.control.Spinner
        NumberofclustersLabel           matlab.ui.control.Label
        DataSwitchLabel                 matlab.ui.control.Label
        DataSwitch                      matlab.ui.control.Switch
        ClusterizeButton                matlab.ui.control.Button
        SelectKDropDownLabel            matlab.ui.control.Label
        SelectKDropDown                 matlab.ui.control.DropDown
        LoadClusterButton               matlab.ui.control.Button
        scalevariablesDropDownLabel     matlab.ui.control.Label
        scalevariablesDropDown          matlab.ui.control.DropDown
        NumberofreplicatesSpinnerLabel  matlab.ui.control.Label
        NumberofreplicatesSpinner       matlab.ui.control.Spinner
        LabelDatasetClustered           matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        TRdataset % Description
        datasetFileName
        colsName
        colFirstPatient
        metModel
        RAS
        datasetsStr
        HMRcoreFLAG
        tempStat
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GeneIDscolDropDown.Visible = 'off';
            app.GeneIDscolDropDownLabel.Visible = 'off';
            app.GeneIdentifierListBox.Visible = 'off';
            app.GeneIdentifierListBoxLabel.Visible = 'off';
            app.FirstpatientcolDropDown.Visible = 'off';
            app.GeneIdentifierListBox.Visible = 'off';
            app.AdddatasetButton.Visible = 'off';
            app.FirstpatientcolDropDownLabel.Visible = 'off';
            app.GeneIdentifierListBoxLabel.Visible = 'off';
            app.DatasetsTextArea.Visible = 'off';
            app.DatasetsTextAreaLabel.Visible = 'off';
            app.DatasetName.Visible = 'off';
            app.DatasetsTextArea.Value = {''};
            app.ModelTextArea.Visible = 'off';
            app.ModelTextAreaLabel.Visible = 'off';
            app.ResolveAandnanrulesSwitch.Visible = 'off';
            app.ConvertlogscaledtodecimalvaluesSwitch.Visible = 'off';
            app.ParallelemodeSwitch.Visible = 'off';
            app.ParallelemodeSwitchLabel.Visible = 'off';
            app.Threshold0transcriptSpinner.Visible = 'off';
            app.ResolveAandnanrulesSwitchLabel.Visible = 'off';
            app.ConvertlogscaledtodecimalvaluesSwitchLabel.Visible = 'off';
            app.Threshold0transcriptSpinnerLabel.Visible = 'off';
            app.ComparedatasetButton.Enable = 'off';
            app.ExportDataButton.Enable = 'off';
            app.HMRcoreFLAG = false;
            app.ColormapButton.Visible = 'off';
            app.pVthrTextArea.Visible = 'off';
            app.pVthrTextAreaLabel.Visible = 'off';
            app.FCthrTextArea.Visible = 'off';
            app.FCthrTextAreaLabel.Visible = 'off';
            app.SelectKDropDown.Visible = 'off';
            app.SelectKDropDownLabel.Visible = 'off';
            app.LoadClusterButton.Visible = 'off';
        end

        % Button pushed function: importDataset
        function importDatasetButtonPushed(app, event)
            app.importDataset.Enable = 'off';
            [app.datasetFileName, datasetPath] = uigetfile({'*.txt';'*.csv';'*.*'}, 'Select dataset');
            
            if ~isequal(app.datasetFileName,0)
                
                fid = fopen([datasetPath, app.datasetFileName]);
                n = fgetl(fid);
                n(regexp(n,'[.,-]'))='_';
                
                s = strsplit(n, '\t');
                if length(s)==1
                    s = strsplit(n, ',');
                    if length(s)==1
                        s = strsplit(n, ';');
                        if length(s)==1
                            app.importDataset.Enable = 'on';
                            error('Only tab-separated-values or command-separated-value file allowed.')
                        end
                    end
                end
                if strcmp(s(end),'')
                    s(end) = [];
                end
                fclose(fid);
                
                app.TRdataset = readtable([datasetPath, app.datasetFileName], 'ReadVariableNames',false, 'HeaderLines', 1);
                app.TRdataset = app.TRdataset(:,1:length(s));
                app.TRdataset.Properties.VariableNames = s;
                app.colsName = app.TRdataset.Properties.VariableNames;
                app.GeneIDscolDropDown.Items = app.colsName;
                app.FirstpatientcolDropDown.Items = app.colsName;
                app.DatasetName.Text = app.datasetFileName;
                msgbox('Dataset Loaded');
                app.importDataset.Enable = 'on';
                
                app.GeneIDscolDropDown.Visible = 'on';
                app.GeneIDscolDropDownLabel.Visible = 'on';
                app.GeneIdentifierListBox.Visible = 'on';
                app.GeneIdentifierListBoxLabel.Visible = 'on';
                app.FirstpatientcolDropDown.Visible = 'on';
                app.GeneIdentifierListBox.Visible = 'on';
                app.MetabolicmodelListBox.Visible = 'on';
                app.AdddatasetButton.Visible = 'on';
                app.FirstpatientcolDropDownLabel.Visible = 'on';
                app.GeneIdentifierListBoxLabel.Visible = 'on';
                app.MetabolicmodelListBoxLabel.Visible = 'on';
                app.DatasetName.Visible = 'on';
            end
        end

        % Button pushed function: AdddatasetButton
        function AdddatasetButtonPushed(app, event)
            app.DatasetsTextArea.Visible = 'on';
            app.DatasetsTextAreaLabel.Visible = 'on';
            
            geneList = app.TRdataset.(app.GeneIDscolDropDown.Value);
            
            p = find(strcmp(app.FirstpatientcolDropDown.Value, app.colsName));
            try
                transcript = app.TRdataset{:,p:end};
            catch
                error('Can not concatenate patient columns. Please check.');
            end
            
            Text = [{app.datasetFileName};{['    Genes: ' num2str(length(geneList))]};{['    Patients: ' num2str(size(transcript,2))]}];
            %             if strcmp(app.DatasetsTextArea.Value, '')
            %                 app.DatasetsTextArea.Value = Text;
            %             else
            %                 app.DatasetsTextArea.Value = [app.DatasetsTextArea.Value; Text];
            %             end
            
            p_pos = strfind(app.datasetFileName, '.');
            %app.datasetsStr.(app.datasetFileName(1:p_pos-1)).transcript = transcript;
            %app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneList = geneList;
            
            if ~isfield(app.datasetsStr, app.datasetFileName(1:p_pos-1))
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).dataset = app.TRdataset;
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colFirstPatient = p;
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colGeneID = find(strcmp(app.TRdataset.Properties.VariableNames, app.GeneIDscolDropDown.Value));
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).patientIDs = app.colsName(p:end);
                if strcmp(app.DatasetsTextArea.Value, '')
                    app.DatasetsTextArea.Value = Text;
                else
                    app.DatasetsTextArea.Value = [app.DatasetsTextArea.Value; Text];
                end
                app.DatasetsListBox.Items = [app.DatasetsListBox.Items, app.datasetFileName(1:p_pos-1)];
            else
                answer = questdlg(['Dataset ', app.datasetFileName(1:p_pos-1), ', already added. Replace it?'], 'Adding dataset', 'Yes','No','No');
                if strcmp(answer, 'Yes')
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).dataset = app.TRdataset;
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colFirstPatient = p;
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colGeneID = find(strcmp(app.TRdataset.Properties.VariableNames, app.GeneIDscolDropDown.Value));
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).patientIDs = app.colsName(p:end);
                    pos_rm = find(strcmp(app.DatasetsTextArea.Value, app.datasetFileName));
                    app.DatasetsTextArea.Value(pos_rm:pos_rm+2)= Text;
                else
                    msgbox('Dataset not loaded.')
                end
            end
            switch app.GeneIdentifierListBox.Value
                case {'Hugo Symbol'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'symbol';
                case {'Hugo ID'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'hgnc_id';
                case {'Ensemble ID'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'ensembl_gene_id';
                case {'Entrez ID'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'entrez_id';
            end


        end

        % Button pushed function: SelectModelButton
        function SelectModelButtonPushed(app, event)
            app.SelectModelButton.Enable = 'off';
            if strcmp(app.MetabolicmodelListBox.Value, 'Recon2.2')
                app.metModel = load('Recon22.mat');
                f = fieldnames(app.metModel);
                app.metModel = app.metModel.(f{1});
                app.metModel.description = 'Recon2.2';
                app.metModel.genIdentifier = 'hgnc_id';
                app.HMRcoreFLAG = false;
            elseif strcmp(app.MetabolicmodelListBox.Value, 'HMRcore')
                app.metModel = load('HMRcore.mat');
                f = fieldnames(app.metModel);
                app.metModel = app.metModel.(f{1});
                app.metModel.description = 'HMRcore';
                app.metModel.genIdentifier = 'hgnc_id';
                app.HMRcoreFLAG = true;
            else
                app.HMRcoreFLAG = false;
                [modelFile, modelPath] = uigetfile({'*.mat';'*.xml';'*.*'}, 'Select metabolic model');
                if modelFile == 0
                    app.SelectModelButton.Enable = 'on';
                    return
                end
                switch modelFile(end-2:end)
                    case 'mat'
                        app.metModel = load([modelPath, modelFile]);
                        f = fieldnames(app.metModel);
                        app.metModel = app.metModel.(f{1});
                    case 'xml'
                        app.metModel =  readCbModel([modelPath, modelFile]);
%                     case ('xls' | 'lsx')
%                         app.metModel =  xls2model([modelPath, modelFile]);
                    otherwise
                        error('Model not supported')
                end
                p_pos = strfind(modelFile, '.');
                app.metModel.description = modelFile(1:p_pos-1);
                switch app.ModelGeneIdentifierListBox.Value
                    case 'Hugo Symbol'
                        app.metModel.genIdentifier = 'symbol';
                    case 'Hugo ID'
                        app.metModel.genIdentifier = 'hgnc_id';
                    case 'Ensemble ID'
                        app.metModel.genIdentifier = 'ensembl_gene_id';
                    case 'Entrez ID'
                        app.metModel.genIdentifier = 'entrez_id';
                end
            end
            msgbox('Metabolic model loaded');
            Text = [{app.metModel.description};{['    Genes: ', num2str(length(app.metModel.genes))]};{['    Reaction: ', num2str(length(app.metModel.rxns))]}; {['        with GPR: ', num2str(length(find(~strcmp(app.metModel.rules, ''))))]}];
            app.ModelTextArea.Value =  Text;
            app.ModelTextArea.Visible = 'on';
            app.ModelTextAreaLabel.Visible = 'on';
            app.SelectModelButton.Enable = 'on';
        end

        % Button pushed function: ComputeRASButton
        function ComputeRASButtonPushed(app, event)
            app.ComputeRASButton.Enable = 'off';
            if isempty(app.DatasetsListBox.Value)
                msgbox('Please add or select at least one dataset');
            elseif ~isstruct(app.metModel)
                msgbox('Please select a metabolic model');
            else
                for i=1:length(app.DatasetsListBox.Value)
                    if ~isfield(app.datasetsStr.(app.DatasetsListBox.Value{i}), 'datasetFilt')
                        dataset_i = app.datasetsStr.(app.DatasetsListBox.Value{i});
                        if ~strcmp(dataset_i.geneIdentifier, app.metModel.genIdentifier) && ~isfield(app.metModel, [app.metModel.genIdentifier, '2', dataset_i.geneIdentifier])
                            dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {dataset_i.geneIdentifier}, [app.metModel.genIdentifier, '2', dataset_i.geneIdentifier, '_log.txt']);
                            app.metModel.([app.metModel.genIdentifier, '2', dataset_i.geneIdentifier]) = dict_i(:,[3 2]);
                            amb = find(strcmp(app.metModel.([app.metModel.genIdentifier, '2', dataset_i.geneIdentifier]).(1), 'ambiguous'), 1);
                            if ~isempty(amb)
                                msgbox(['Some genes are ambiguos, please check file', app.metModel.genIdentifier, '2', dataset_i.geneIdentifier, '_log.txt',' for details'])
                            end
                            % filtro dataset
                            [app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt, ~, genNotInModel, genDuplicated] = extractGeneRow(app.metModel, dataset_i.dataset(:,[dataset_i.colGeneID, dataset_i.colFirstPatient:end]), 1, dict_i(:,[3 2]));
                            colS = 3;
                        elseif isfield(app.metModel, [app.metModel.genIdentifier, '2', dataset_i.geneIdentifier])
                            [app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt, ~, genNotInModel, genDuplicated] = extractGeneRow(app.metModel, dataset_i.dataset(:,[dataset_i.colGeneID, dataset_i.colFirstPatient:end]), 1, app.metModel.([app.metModel.genIdentifier, '2', dataset_i.geneIdentifier]));
                            colS = 3;
                        else
                            [app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt, ~, genNotInModel, genDuplicated] = extractGeneRow(app.metModel, dataset_i.dataset(:,[dataset_i.colGeneID, dataset_i.colFirstPatient:end]), 1);
                            colS = 2;
                        end
                        %BRCAscore_abs_CORE = getDataScored(HMRcore, BRCAfilt_CORE.Input, BRCAfilt_CORE{:,3:end}, BRCAfilt_CORE.Properties.VariableNames(3:end), true);
                        disp('Dataset(s) filtered');
                    end
                    if ~isfield(app.datasetsStr.(app.DatasetsListBox.Value{i}), 'datasetSCORE')
                        if strcmp(app.ParallelemodeSwitch.Value, 'No')
                            app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetSCORE = getDataScored(app.metModel, app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt.(1), app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt{:, colS:end}, app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt.Properties.VariableNames(colS:end), false);
                        else
                            app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetSCORE = getDataScored(app.metModel, app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt.(1), app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt{:, colS:end}, app.datasetsStr.(app.DatasetsListBox.Value{i}).datasetFilt.Properties.VariableNames(colS:end), true);
                            
                        end
                        app.DatasetswithRASListBox.Items =  [app.DatasetswithRASListBox.Items, app.DatasetsListBox.Value{i}];
                    else
                        disp(['RAS for dataset ', app.DatasetsListBox.Value{i}, ' already computed.']);
                    end
                end
                %                     if ~strcmp(dataset_i.geneIdentifier, 'Hugo ID') %crea dizionario traducendo geni del modello
                %                         switch dataset_i.geneIdentifier
                %                             case 'Hugo Symbol'
                %                                 if ~isfield(app.metModel, 'HGNCid2Symb')
                %                                     dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {'symbol'}, 'hgncid2symbol_log.txt');
                %                                     app.metModel.HGNCid2Symb = dict_i(:,[3 2]);
                %                                     amb = find(strcmp(HMRcore.HGNCid2Symb.(1), 'ambiguous'), 1);
                %                                     if ~isempty(amb)
                %                                         msgbox('Some genes are ambiguos, please check file hgncid2symbol_log.txt for details')
                %                                     end
                %                                 end
                %                             case 'Ensemble ID'
                %                                 if ~isfield(app.metModel, 'HGNCid2ENS')
                %                                     dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {'ensembl_gene_id'}, 'hgncid2ensemble_log.txt');
                %                                     app.metModel.HGNCid2ENS = dict_i(:,[3 2]);
                %                                     amb = find(strcmp(HMRcore.HGNCid2ENS.(1), 'ambiguous'), 1);
                %                                     if ~isempty(amb)
                %                                         msgbox('Some genes are ambiguos, please check file hgncid2ensemble_log.txt for details')
                %                                     end
                %                                 end
                %                             case 'Entrez ID'
                %                                 if ~isfield(app.metModel, 'HGNCid2Etrz')
                %                                     dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {'entrez_id'}, 'hgncid2entrez_log.txt');
                %                                     app.metModel.HGNCid2Etrz = dict_i(:,[3 2]);
                %                                     amb = find(strcmp(HMRcore.HGNCid2Etrz.(1), 'ambiguous'), 1);
                %                                     if ~isempty(amb)
                %                                         msgbox('Some genes are ambiguos, please check file hgncid2entrez_log.txt for details')
                %                                     end
                %                                 end
                
                
                %Text = [{app.metModel.description}; {['    Genes: ', num2str(length(app.metModel.genes))]};{['    Reaction: ', num2str(length(app.metModel.rxns))]}; {['        with rules: ', num2str(length(find(~strcmp(app.metModel.rules, ''))))]}];
                msgbox('dataset(s) ready for analysis');
                app.ComparedatasetButton.Enable = 'on';
                app.ExportDataButton.Enable = 'on';
                app.SelectModelButton.Enable = 'off';
                app.MetabolicmodelListBox.Enable = 'off';
                app.ModelGeneIdentifierListBox.Enable = 'off';
                
            end
            app.ComputeRASButton.Enable = 'on';


        end

        % Value changed function: MetabolicmodelListBox
        function MetabolicmodelListBoxValueChanged(app, event)
            value = app.MetabolicmodelListBox.Value;
            if (strcmp(value, 'HMRcore') || strcmp(value, 'Recon2.2'))
                app.ModelGeneIdentifierListBox.Value = 'Hugo ID';
                app.ModelGeneIdentifierLabel.Enable = 'off';
                app.ModelGeneIdentifierListBox.Enable = 'off';
            else
                app.ModelGeneIdentifierListBox.Enable = 'on';
                app.ModelGeneIdentifierLabel.Enable = 'on';
            end
        end

        % Value changed function: AdvancedOptionsSwitch
        function AdvancedOptionsSwitchValueChanged(app, event)
            value = app.AdvancedOptionsSwitch.Value;
            if strcmp(value, 'Default')
                app.ResolveAandnanrulesSwitch.Visible = 'off';
                app.ResolveAandnanrulesSwitch.Value = 'A';
                app.ConvertlogscaledtodecimalvaluesSwitch.Visible = 'off';
                app.ConvertlogscaledtodecimalvaluesSwitch.Value = 'No';
                app.Threshold0transcriptSpinner.Visible = 'off';
                app.Threshold0transcriptSpinner.Value = 0.0;
                app.ResolveAandnanrulesSwitchLabel.Visible = 'off';
                app.ConvertlogscaledtodecimalvaluesSwitchLabel.Visible = 'off';
                app.Threshold0transcriptSpinnerLabel.Visible = 'off';
                app.ParallelemodeSwitch.Visible = 'off';
                app.ParallelemodeSwitch.Value = 'No';
                app.ParallelemodeSwitchLabel.Visible = 'off';
            else
                app.ResolveAandnanrulesSwitch.Visible = 'on';
                app.ConvertlogscaledtodecimalvaluesSwitch.Visible = 'on';
                app.Threshold0transcriptSpinner.Visible = 'on';
                app.ResolveAandnanrulesSwitchLabel.Visible = 'on';
                app.ConvertlogscaledtodecimalvaluesSwitchLabel.Visible = 'on';
                app.Threshold0transcriptSpinnerLabel.Visible = 'on';
                app.ParallelemodeSwitch.Visible = 'on';
                app.ParallelemodeSwitchLabel.Visible = 'on';
            end
        end

        % Button pushed function: ComparedatasetButton
        function ComparedatasetButtonPushed(app, event)
            % creare strutture con i database con score.
            % calcFCpVonCluster(clstCOREk3_1vs3, 'method', 'combinatorial', 'test', 'kstest2', 'normalization', 'none', 'log2fc', true, 'absFC', true, 'header', true);
            if length(app.DatasetswithRASListBox.Value) < 2
                msgbox('Select at least two datasets');
                return
            end
            app.ComparedatasetButton.Enable = 'on';
            for i=1:length(app.DatasetswithRASListBox.Value)
                StrAnalysis.(app.DatasetswithRASListBox.Value{i}) = convertTable_Cell(app.datasetsStr.(app.DatasetswithRASListBox.Value{i}).datasetSCORE);
            end
            
            if strcmp(app.ModeDropDown.Value, 'Pairwise')
                method = 'combinatorial';
            else
                method = 'single';
            end
            
            switch app.HypotesistestDropDown.Value
                case 'Kolmogorov - Smirnov'
                    test = 'kstest2';
                case 'Kruskal - Wallis'
                    test = 'kruskalwallis';
                case 't student'
                    test = 'ttest2';
                case 'One way anova'
                    test = 'anova1';
                case 'Do not compute'
                    test = 'none';
            end
            
            switch app.FoldChangeDropDown.Value
                case 'Compute and log2 scale'
                    fc = true;
                    log2fc = true;
                    absFC = true;
                case 'Compute'
                    fc = true;
                    log2fc = false;
                    absFC = false;
                case 'Do not compute'
                    fc = false;
                    log2fc = false;
                    absFC = false;
            end
            
            [fileName,filePath] = uiputfile('*.txt', 'Save statistical analysis');
            
            tableStat = calcFCpVonCluster(StrAnalysis, 'method', method , 'test', test , 'normalization', 'none', 'fc', fc, 'log2fc', log2fc, 'absFC', absFC, 'header', true);
            for i=1:size(tableStat, 1)
                idx = findIdString_v2(tableStat.(1)(i), app.metModel.rxns);
                tableStat.Pathway(i) = app.metModel.subSystems(idx);
                tableStat.Description(i) = app.metModel.rxnNames(idx);
                tableStat.GeneRule(i) = app.metModel.grRules(idx);
                tableStat.Reaction(i) = printRxnFormula(app.metModel, tableStat.(1)(i));
            end
            writetable(tableStat, [filePath, fileName], 'Delimiter', '\t');
            app.tempStat.tableStat = tableStat;
            app.tempStat.FC = app.FoldChangeDropDown.Value;
            app.tempStat.pv = app.HypotesistestDropDown.Value;
            msgbox('File saved');
            app.ComparedatasetButton.Enable = 'on';
            
            if app.HMRcoreFLAG
                app.ColormapButton.Visible = 'on';
                app.pVthrTextArea.Visible = 'on';
                app.pVthrTextAreaLabel.Visible = 'on';
                app.FCthrTextArea.Visible = 'on';
                app.FCthrTextAreaLabel.Visible = 'on';
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if ~isempty(app.DatasetswithRASListBox.Value)
                fileName = 0;
                [fileName,filePath] = uiputfile('*.mat', ['Save dataset(s)']);
                if fileName == 0
                    return
                end
                for i=1:length(app.DatasetswithRASListBox.Value)
                    %saveVar = app.datasetsStr.(app.DatasetswithRASListBox.Value{i});
                    %n = strsplit(fileName, '.');
                    %                     try
                    S.(app.DatasetswithRASListBox.Value{i}) = app.datasetsStr.(app.DatasetswithRASListBox.Value{i});
                    
                    %                     catch
                    %                         msgbox('Failed, invalid file name');
                    %                     end
                    
                end
                save([filePath,fileName], '-struct', 'S');
                msgbox('Dataset saved.');
            else
                msgbox('Select at least one dataset');
            end
        end

        % Button pushed function: ColormapButton
        function ColormapButtonPushed(app, event)
            [fileName,filePath] = uiputfile({'*.svg';'*.html';}, 'Save metabolic map');
            if fileName == 0
                return
            end
            colName = app.tempStat.tableStat.Properties.VariableNames;
            idFCcol = find(startsWith(colName, 'FC_'));
            if ~isempty(idFCcol)
                pvThr = str2double(app.pVthrTextArea.Value);
                FCThr = str2double(app.FCthrTextArea.Value);
                if strcmp(app.tempStat.FC, 'Compute and log2 scale')
                    FCThr = log2(1+FCThr);
                end
                
                if strcmp(app.tempStat.FC, 'Do not compute')
                    msgbox('Before enrich map, compute Fold Change.')
                end                
                
                if strcmp(app.tempStat.pv, 'Do not compute')
                    app.tempStat.tableStat.pv = zeros(size(app.tempStat.tableStat, 1), 1) - 1;    
                    if length(idFCcol)==1
                        datasetsName = colName{idFCcol}(4:end);
                        
                        tempTable = [app.tempStat.tableStat(:,1), app.tempStat.tableStat(:,end), app.tempStat.tableStat(:,idFCcol)];
                        
                        colorZscore_HMRmap('HMRcoreMap.svg', tempTable, 2, 3, pvThr, FCThr, strrep([datasetsName, ' Fold Change > ', app.FCthrTextArea.Value{:}, ', p-Value < ', app.pVthrTextArea.Value{:}], '_', ' '), [filePath, fileName]);
                    else
                        for i = 1:length(idFCcol)
                            datasetsName = colName{idFCcol(i)}(4:end);
                            
                             tempTable = [app.tempStat.tableStat(:,1), app.tempStat.tableStat(:,end), app.tempStat.tableStat(:,idFCcol(i))];
              
                            colorZscore_HMRmap('HMRcoreMap.svg', tempTable, 2, 3, pvThr, FCThr, strrep([datasetsName, ' Fold Change > ', app.FCthrTextArea.Value{:}, ', p-Value < ', app.pVthrTextArea.Value{:}], '_', ' '), [filePath, datasetsName,'_',fileName]);
                        end
                    end       
                    
                else
                    if length(idFCcol)==1
                        datasetsName = colName{idFCcol}(4:end);
                        colorZscore_HMRmap('HMRcoreMap.svg', app.tempStat.tableStat, idFCcol-1, idFCcol, pvThr, FCThr, strrep([datasetsName, ' Fold Change > ', app.FCthrTextArea.Value{:}, ', p-Value < ', app.pVthrTextArea.Value{:}], '_', ' '), [filePath, fileName]);
                    else
                        for i = 1:length(idFCcol)
                            datasetsName = colName{idFCcol(i)}(4:end);
                            colorZscore_HMRmap('HMRcoreMap.svg', app.tempStat.tableStat, idFCcol(i)-1, idFCcol(i), pvThr, FCThr, strrep([datasetsName, ' Fold Change > ', app.FCthrTextArea.Value{:}, ', p-Value < ', app.pVthrTextArea.Value{:}], '_', ' '), [filePath, datasetsName,'_',fileName]);
                        end
                    end              
                    
                end
                
                
            else
                msgbox('When compare datasets Fold Change has to be computed')
            end
            msgbox('Map enriched');
        end

        % Button pushed function: ClusterizeButton
        function ClusterizeButtonPushed(app, event)
            if length(app.DatasetswithRASListBox.Value) ~= 1
                msgbox('Select one datasets');
                return
            end
            
            [fileName,filePath] = uiputfile('*.txt', 'Specific directory and prefix of clustering files');
            if fileName == 0
                return
            else
                fileName = strsplit(fileName, '.');
                fileName = fileName{1};
            end
            
            fK = app.FromKSpinner.Value;
            tK = app.toKSpinner.Value;
            
            patList = app.datasetsStr.(app.DatasetswithRASListBox.Value{1}).patientIDs;
            
            
            if strcmp(app.DataSwitch.Value, 'Score')
                DataMatrix = app.datasetsStr.(app.DatasetswithRASListBox.Value{1}).datasetSCORE;
            else
                DataMatrix = app.datasetsStr.(app.DatasetswithRASListBox.Value{1}).datasetFilt;
            end
            
            DataMatrix = DataMatrix{:,size(DataMatrix, 2) - length(patList) + 1:end};
            
            %normalize and nan remove
            
            switch app.scalevariablesDropDown.Value
                case 'divide row by max'
                    max_score = max(DataMatrix,[], 2);
                    DataMatrix = DataMatrix./max_score;
                    DataMatrix(~any(~isnan(DataMatrix), 2),:)=[];
                case 'divide col by max'
                    max_score = max(DataMatrix,[], 1);
                    DataMatrix = DataMatrix./max_score;
                    DataMatrix(~any(~isnan(DataMatrix), 2),:)=[];
                case 'none'
                    DataMatrix(~any(~isnan(DataMatrix), 2),:)=[];
            end
            
            for k=fK:tK
                %classID = kmeans(DataMatrix', k, 'Replicates', app.NumberofreplicatesSpinner.Value);
                classID = kmeans(DataMatrix', k, 'Replicates', 2);
                app.datasetsStr.(app.DatasetswithRASListBox.Value{1}).k.(['K_',int2str(k)]) = classID;
                for i=1:k
                    tableOUT = table();
                    tableOUT.ID = patList(classID==i);
                    writetable(tableOUT, strcat(filePath, fileName, '_K', num2str(k), '_', num2str(i)), 'WriteVariableNames', false);
                end
                
            end
            msgbox('Cluster files saved')
            app.LabelDatasetClustered.Text = app.DatasetswithRASListBox.Value{1};
            app.SelectKDropDown.Items = strsplit(num2str(fK:tK), ' ');
            app.SelectKDropDown.Visible = 'on';
            app.SelectKDropDownLabel.Visible = 'on';
            app.LoadClusterButton.Visible = 'on';
            
        end

        % Button pushed function: LoadClusterButton
        function LoadClusterButtonPushed(app, event)
            DataStr = app.datasetsStr.(app.LabelDatasetClustered.Text);
            k = str2double(app.SelectKDropDown.Value);
            
            classID = DataStr.k.(['K_', app.SelectKDropDown.Value]);
            
            for i=1:k
                dataStr_i = DataStr;
                idx_i = classID~=i;
                
                colRem = zeros(1,size(dataStr_i.dataset,2));
                colRem(end-length(idx_i)+1:end) = idx_i;
                dataStr_i.dataset(:,logical(colRem)) = [];
                
                colRem = zeros(1,size(dataStr_i.datasetFilt,2));
                colRem(end-length(idx_i)+1:end) = idx_i;
                dataStr_i.datasetFilt(:,logical(colRem)) = [];
                
                if isfield(dataStr_i, 'datasetSCORE')
                    colRem = zeros(1,size(dataStr_i.datasetSCORE,2));
                    colRem(end-length(idx_i)+1:end) = idx_i;
                    dataStr_i.datasetSCORE(:,logical(colRem)) = [];
                end
                
                dataStr_i.patientIDs(idx_i) = [];
                
                dataStr_i = rmfield(dataStr_i,'k');
                
                datasetName = [app.LabelDatasetClustered.Text, '_K', app.SelectKDropDown.Value, '_',num2str(i)];
                
                app.datasetsStr.(datasetName) = dataStr_i;
                
                pos_rm = find(strcmp(app.DatasetswithRASListBox.Items, datasetName));
                
                if isempty(pos_rm)
                    app.DatasetswithRASListBox.Items =  [app.DatasetswithRASListBox.Items, datasetName];
                else
                    app.DatasetswithRASListBox.Items(pos_rm) = {datasetName};
                end
                
            end
            msgbox('Datasets loaded')
        end

        % Value changing function: FromKSpinner
        function FromKSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if app.toKSpinner.Value < changingValue
                app.toKSpinner.Value = changingValue;
            end
        end

        % Value changing function: toKSpinner
        function toKSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if app.FromKSpinner.Value > changingValue
                app.FromKSpinner.Value = changingValue;
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaREAUIFigure = uifigure;
            app.MaREAUIFigure.Name = 'MaREA';
            setAutoResize(app, app.MaREAUIFigure, true)
            app.TabGroup = uitabgroup(app.MaREAUIFigure);
            app.DatasetTab = uitab(app.TabGroup);
            app.DatasetTab.Title = 'Dataset';
            app.DatasetTab.Units = 'pixels';
            app.importDataset = uibutton(app.DatasetTab, 'push');
            app.importDataset.ButtonPushedFcn = createCallbackFcn(app, @importDatasetButtonPushed, true);
            app.importDataset.Text = 'Select dataset';
            app.AdddatasetButton = uibutton(app.DatasetTab, 'push');
            app.AdddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @AdddatasetButtonPushed, true);
            app.AdddatasetButton.Text = 'Add dataset';
            app.DatasetLabel = uilabel(app.DatasetTab);
            app.DatasetLabel.Text = 'Dataset';
            app.GeneIDscolDropDownLabel = uilabel(app.DatasetTab);
            app.GeneIDscolDropDownLabel.Text = 'Gene IDs col';
            app.GeneIDscolDropDown = uidropdown(app.DatasetTab);
            app.FirstpatientcolDropDownLabel = uilabel(app.DatasetTab);
            app.FirstpatientcolDropDownLabel.Text = 'First patient col';
            app.FirstpatientcolDropDown = uidropdown(app.DatasetTab);
            app.GeneIdentifierListBoxLabel = uilabel(app.DatasetTab);
            app.GeneIdentifierListBoxLabel.Text = 'Gene Identifier';
            app.GeneIdentifierListBox = uilistbox(app.DatasetTab);
            app.GeneIdentifierListBox.Items = {'Hugo Symbol', 'Hugo ID', 'Ensemble ID', 'Entrez ID'};
            app.GeneIdentifierListBox.Value = 'Hugo Symbol';
            app.DatasetsTextAreaLabel = uilabel(app.DatasetTab);
            app.DatasetsTextAreaLabel.Text = 'Datasets';
            app.DatasetsTextArea = uitextarea(app.DatasetTab);
            app.DatasetsTextArea.Editable = 'off';
            app.DatasetsTextArea.Value = {''; ''};
            app.DatasetName = uilabel(app.DatasetTab);
            app.DatasetName.Text = '';
            app.MetabolicModelTab = uitab(app.TabGroup);
            app.MetabolicModelTab.Title = 'Metabolic Model';
            app.MetabolicModelTab.Units = 'pixels';
            app.MetabolicmodelListBoxLabel = uilabel(app.MetabolicModelTab);
            app.MetabolicmodelListBoxLabel.Text = 'Metabolic model';
            app.MetabolicmodelListBox = uilistbox(app.MetabolicModelTab);
            app.MetabolicmodelListBox.Items = {'HMRcore', 'Recon2.2', 'Custom'};
            app.MetabolicmodelListBox.ValueChangedFcn = createCallbackFcn(app, @MetabolicmodelListBoxValueChanged, true);
            app.MetabolicmodelListBox.Value = 'HMRcore';
            app.MetabolicmodelLabel = uilabel(app.MetabolicModelTab);
            app.MetabolicmodelLabel.Text = 'Metabolic model';
            app.ModelTextAreaLabel = uilabel(app.MetabolicModelTab);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.MetabolicModelTab);
            app.ModelTextArea.Editable = 'off';
            app.ModelTextArea.Value = {''; ''};
            app.SelectModelButton = uibutton(app.MetabolicModelTab, 'push');
            app.SelectModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectModelButtonPushed, true);
            app.SelectModelButton.Text = 'Select Model';
            app.ModelGeneIdentifierLabel = uilabel(app.MetabolicModelTab);
            app.ModelGeneIdentifierLabel.Enable = 'off';
            app.ModelGeneIdentifierLabel.Text = {'Model Gene '; 'Identifier'};
            app.ModelGeneIdentifierListBox = uilistbox(app.MetabolicModelTab);
            app.ModelGeneIdentifierListBox.Items = {'Hugo Symbol', 'Hugo ID', 'Ensemble ID', 'Entrez ID'};
            app.ModelGeneIdentifierListBox.Enable = 'off';
            app.ModelGeneIdentifierListBox.Value = 'Hugo ID';
            app.PreparedataTab = uitab(app.TabGroup);
            app.PreparedataTab.Title = 'Prepare data';
            app.PreparedataTab.Units = 'pixels';
            app.DatasetsListBoxLabel = uilabel(app.PreparedataTab);
            app.DatasetsListBoxLabel.Text = 'Datasets';
            app.DatasetsListBox = uilistbox(app.PreparedataTab);
            app.DatasetsListBox.Items = {};
            app.DatasetsListBox.Multiselect = 'on';
            app.DatasetsListBox.Value = {};
            app.ComputeRASButton = uibutton(app.PreparedataTab, 'push');
            app.ComputeRASButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeRASButtonPushed, true);
            app.ComputeRASButton.Text = 'Compute RAS';
            app.AdvancedOptionsSwitchLabel = uilabel(app.PreparedataTab);
            app.AdvancedOptionsSwitchLabel.Text = 'Advanced Options';
            app.AdvancedOptionsSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.AdvancedOptionsSwitch.Items = {'Default', 'Custom'};
            app.AdvancedOptionsSwitch.ValueChangedFcn = createCallbackFcn(app, @AdvancedOptionsSwitchValueChanged, true);
            app.AdvancedOptionsSwitch.Value = 'Default';
            app.ResolveAandnanrulesSwitchLabel = uilabel(app.PreparedataTab);
            app.ResolveAandnanrulesSwitchLabel.Enable = 'off';
            app.ResolveAandnanrulesSwitchLabel.Text = 'Resolve (A and nan) rules';
            app.ResolveAandnanrulesSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.ResolveAandnanrulesSwitch.Items = {'A', 'nan'};
            app.ResolveAandnanrulesSwitch.Enable = 'off';
            app.ResolveAandnanrulesSwitch.Value = 'A';
            app.ConvertlogscaledtodecimalvaluesSwitchLabel = uilabel(app.PreparedataTab);
            app.ConvertlogscaledtodecimalvaluesSwitchLabel.Enable = 'off';
            app.ConvertlogscaledtodecimalvaluesSwitchLabel.Text = 'Convert log scaled to decimal values';
            app.ConvertlogscaledtodecimalvaluesSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.ConvertlogscaledtodecimalvaluesSwitch.Items = {'No', 'Yes'};
            app.ConvertlogscaledtodecimalvaluesSwitch.Enable = 'off';
            app.ConvertlogscaledtodecimalvaluesSwitch.Value = 'No';
            app.Threshold0transcriptSpinnerLabel = uilabel(app.PreparedataTab);
            app.Threshold0transcriptSpinnerLabel.Enable = 'off';
            app.Threshold0transcriptSpinnerLabel.Text = 'Threshold 0 transcript';
            app.Threshold0transcriptSpinner = uispinner(app.PreparedataTab);
            app.Threshold0transcriptSpinner.ValueDisplayFormat = '%.1f';
            app.Threshold0transcriptSpinner.Enable = 'off';
            app.ParallelemodeSwitchLabel = uilabel(app.PreparedataTab);
            app.ParallelemodeSwitchLabel.Text = 'Parallele mode';
            app.ParallelemodeSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.ParallelemodeSwitch.Items = {'No', 'Yes'};
            app.ParallelemodeSwitch.Value = 'No';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.Units = 'pixels';
            app.DatasetswithRASListBoxLabel = uilabel(app.AnalysisTab);
            app.DatasetswithRASListBoxLabel.Text = {'Datasets'; 'with RAS'};
            app.DatasetswithRASListBox = uilistbox(app.AnalysisTab);
            app.DatasetswithRASListBox.Items = {};
            app.DatasetswithRASListBox.Multiselect = 'on';
            app.DatasetswithRASListBox.Value = {};
            app.TabGroup2 = uitabgroup(app.AnalysisTab);
            app.EnrichmentTab = uitab(app.TabGroup2);
            app.EnrichmentTab.Title = 'Enrichment';
            app.EnrichmentTab.Units = 'pixels';
            app.ComparedatasetButton = uibutton(app.EnrichmentTab, 'push');
            app.ComparedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ComparedatasetButtonPushed, true);
            app.ComparedatasetButton.Text = 'Compare dataset';
            app.ModeDropDownLabel = uilabel(app.EnrichmentTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.EnrichmentTab);
            app.ModeDropDown.Items = {'Pairwise', 'One vs Others'};
            app.ModeDropDown.Value = 'Pairwise';
            app.HypotesistestDropDownLabel = uilabel(app.EnrichmentTab);
            app.HypotesistestDropDownLabel.Text = 'Hypotesis test';
            app.HypotesistestDropDown = uidropdown(app.EnrichmentTab);
            app.HypotesistestDropDown.Items = {'Kolmogorov - Smirnov', 'Kruskal - Wallis', 't student', 'One way anova', 'Do not compute'};
            app.HypotesistestDropDown.Value = 'Kolmogorov - Smirnov';
            app.FoldChangeDropDownLabel = uilabel(app.EnrichmentTab);
            app.FoldChangeDropDownLabel.Text = 'Fold Change';
            app.FoldChangeDropDown = uidropdown(app.EnrichmentTab);
            app.FoldChangeDropDown.Items = {'Compute and log2 scale', 'Compute', 'Do not compute'};
            app.FoldChangeDropDown.Value = 'Compute and log2 scale';
            app.ColormapButton = uibutton(app.EnrichmentTab, 'push');
            app.ColormapButton.ButtonPushedFcn = createCallbackFcn(app, @ColormapButtonPushed, true);
            app.ColormapButton.Text = 'Color map';
            app.pVthrTextAreaLabel = uilabel(app.EnrichmentTab);
            app.pVthrTextAreaLabel.Text = 'pV - thr';
            app.pVthrTextArea = uitextarea(app.EnrichmentTab);
            app.pVthrTextArea.Value = {'0.05'};
            app.FCthrTextAreaLabel = uilabel(app.EnrichmentTab);
            app.FCthrTextAreaLabel.Text = 'FC - thr';
            app.FCthrTextArea = uitextarea(app.EnrichmentTab);
            app.FCthrTextArea.Value = {'0.2'};
            app.KmeanclusteringTab = uitab(app.TabGroup2);
            app.KmeanclusteringTab.Title = 'K mean clustering';
            app.KmeanclusteringTab.Units = 'pixels';
            app.FromKLabel = uilabel(app.KmeanclusteringTab);
            app.FromKLabel.Text = 'From K ';
            app.FromKSpinner = uispinner(app.KmeanclusteringTab);
            app.FromKSpinner.ValueChangingFcn = createCallbackFcn(app, @FromKSpinnerValueChanging, true);
            app.FromKSpinner.Limits = [2 Inf];
            app.FromKSpinner.RoundFractionalValues = 'on';
            app.FromKSpinner.ValueDisplayFormat = '%.0f';
            app.FromKSpinner.Value = 2;
            app.toKSpinnerLabel = uilabel(app.KmeanclusteringTab);
            app.toKSpinnerLabel.Text = 'to K';
            app.toKSpinner = uispinner(app.KmeanclusteringTab);
            app.toKSpinner.ValueChangingFcn = createCallbackFcn(app, @toKSpinnerValueChanging, true);
            app.toKSpinner.Limits = [2 Inf];
            app.toKSpinner.RoundFractionalValues = 'on';
            app.toKSpinner.ValueDisplayFormat = '%.0f';
            app.toKSpinner.Value = 9;
            app.NumberofclustersLabel = uilabel(app.KmeanclusteringTab);
            app.NumberofclustersLabel.Text = 'Number of clusters';
            app.DataSwitchLabel = uilabel(app.KmeanclusteringTab);
            app.DataSwitchLabel.Text = 'Data';
            app.DataSwitch = uiswitch(app.KmeanclusteringTab, 'slider');
            app.DataSwitch.Items = {'Metabolic transcript', 'Score'};
            app.DataSwitch.Value = 'Score';
            app.ClusterizeButton = uibutton(app.KmeanclusteringTab, 'push');
            app.ClusterizeButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterizeButtonPushed, true);
            app.ClusterizeButton.Text = 'Clusterize';
            app.SelectKDropDownLabel = uilabel(app.KmeanclusteringTab);
            app.SelectKDropDownLabel.Text = 'Select K';
            app.SelectKDropDown = uidropdown(app.KmeanclusteringTab);
            app.SelectKDropDown.Items = {};
            app.SelectKDropDown.Value = {};
            app.LoadClusterButton = uibutton(app.KmeanclusteringTab, 'push');
            app.LoadClusterButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClusterButtonPushed, true);
            app.LoadClusterButton.Text = 'Load Cluster';
            app.scalevariablesDropDownLabel = uilabel(app.KmeanclusteringTab);
            app.scalevariablesDropDownLabel.Text = 'scale variables';
            app.scalevariablesDropDown = uidropdown(app.KmeanclusteringTab);
            app.scalevariablesDropDown.Items = {'divide row by max', 'divide col by max', 'none'};
            app.scalevariablesDropDown.Value = 'divide row by max';
            app.NumberofreplicatesSpinnerLabel = uilabel(app.KmeanclusteringTab);
            app.NumberofreplicatesSpinnerLabel.Text = 'Number of replicates';
            app.NumberofreplicatesSpinner = uispinner(app.KmeanclusteringTab);
            app.NumberofreplicatesSpinner.Step = 10;
            app.NumberofreplicatesSpinner.Limits = [1 Inf];
            app.NumberofreplicatesSpinner.RoundFractionalValues = 'on';
            app.NumberofreplicatesSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofreplicatesSpinner.Value = 100;
            app.LabelDatasetClustered = uilabel(app.KmeanclusteringTab);
            app.LabelDatasetClustered.Text = '';
            app.ExportDataButton = uibutton(app.AnalysisTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MaREA()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaREAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaREAUIFigure)
        end
    end
end